classdef LatestPokerUpdate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        BGM                 matlab.ui.control.DropDown
        SoundLabel          matlab.ui.control.Label
        WinningHand         matlab.ui.control.Label
        Panel               matlab.ui.container.Panel
        GridLayout          matlab.ui.container.GridLayout
        RoyalFlushLabel     matlab.ui.control.Label
        StraightFlushLabel  matlab.ui.control.Label
        ofaKind4Label       matlab.ui.control.Label
        FullHouseLabel      matlab.ui.control.Label
        FlushLabel          matlab.ui.control.Label
        StraightLabel       matlab.ui.control.Label
        ofaKind3Label       matlab.ui.control.Label
        Pair2Label          matlab.ui.control.Label
        JacksorBetterLabel  matlab.ui.control.Label
        L95                 matlab.ui.control.Label
        L94                 matlab.ui.control.Label
        L93                 matlab.ui.control.Label
        L92                 matlab.ui.control.Label
        L91                 matlab.ui.control.Label
        L85                 matlab.ui.control.Label
        L84                 matlab.ui.control.Label
        L83                 matlab.ui.control.Label
        L82                 matlab.ui.control.Label
        L81                 matlab.ui.control.Label
        L75                 matlab.ui.control.Label
        L74                 matlab.ui.control.Label
        L73                 matlab.ui.control.Label
        L72                 matlab.ui.control.Label
        L71                 matlab.ui.control.Label
        L65                 matlab.ui.control.Label
        L64                 matlab.ui.control.Label
        L63                 matlab.ui.control.Label
        L62                 matlab.ui.control.Label
        L61                 matlab.ui.control.Label
        L55                 matlab.ui.control.Label
        L54                 matlab.ui.control.Label
        L53                 matlab.ui.control.Label
        L52                 matlab.ui.control.Label
        L51                 matlab.ui.control.Label
        L45                 matlab.ui.control.Label
        L44                 matlab.ui.control.Label
        L43                 matlab.ui.control.Label
        L42                 matlab.ui.control.Label
        L41                 matlab.ui.control.Label
        L35                 matlab.ui.control.Label
        L34                 matlab.ui.control.Label
        L33                 matlab.ui.control.Label
        L32                 matlab.ui.control.Label
        L31                 matlab.ui.control.Label
        L25                 matlab.ui.control.Label
        L24                 matlab.ui.control.Label
        L23                 matlab.ui.control.Label
        L22                 matlab.ui.control.Label
        L21                 matlab.ui.control.Label
        L15                 matlab.ui.control.Label
        L14                 matlab.ui.control.Label
        L13                 matlab.ui.control.Label
        L12                 matlab.ui.control.Label
        L11                 matlab.ui.control.Label
        B9                  matlab.ui.control.Label
        B8                  matlab.ui.control.Label
        B7                  matlab.ui.control.Label
        B6                  matlab.ui.control.Label
        B5                  matlab.ui.control.Label
        B4                  matlab.ui.control.Label
        B3                  matlab.ui.control.Label
        B2                  matlab.ui.control.Label
        B1                  matlab.ui.control.Label
        Card1               matlab.ui.control.Image
        Card2               matlab.ui.control.Image
        Card3               matlab.ui.control.Image
        Card4               matlab.ui.control.Image
        Card5               matlab.ui.control.Image
        C5Held              matlab.ui.control.Label
        C4Held              matlab.ui.control.Label
        C3Held              matlab.ui.control.Label
        C2Held              matlab.ui.control.Label
        C1Held              matlab.ui.control.Label
        Replace             matlab.ui.control.Button
        Deal                matlab.ui.control.Button
        playAgain           matlab.ui.control.Label
        Pot                 matlab.ui.control.EditField
        YourMoney           matlab.ui.control.EditField
        YourMoneyLabel      matlab.ui.control.Label
        Label               matlab.ui.control.Label
        BetButton           matlab.ui.control.Button
        BetButtonSub        matlab.ui.control.Button
        BetButton100        matlab.ui.control.Button
        BetButtonSub100     matlab.ui.control.Button
        BetSoul             matlab.ui.control.Button
        Image               matlab.ui.control.Image
    end

    properties (Access = private)
        %Card information
        cards = {"C2.png", 2, 'c'; "C3.png", 3, 'c'; ...
            "C4.png", 4, 'c'; "C5.png", 5, 'c'; "C6.png", 6, 'c'; ...
            "C7.png", 7, 'c'; "C8.png", 8, 'c'; "C9.png", 9, 'c'; ...
            "C10.png", 10, 'c'; "CJ.png", 11, 'c'; "CQ.png", 12, 'c'; ...
            "CK.png", 13, 'c'; "CA.png", 14, 'c'; ...
            "D2.png", 2, 'd'; "D3.png", 3, 'd'; ...
            "D4.png", 4, 'd'; "D5.png", 5, 'd'; "D6.png", 6, 'd'; ...
            "D7.png", 7, 'd'; "D8.png", 8, 'd'; "D9.png", 9, 'd'; ...
            "D10.png", 10, 'd'; "DJ.png", 11, 'd'; "DQ.png", 12, 'd'; ...
            "DK.png", 13, 'd'; "DA.png", 14, 'd'; ...
            "H2.png", 2, 'h'; "H3.png", 3, 'h'; ...
            "H4.png", 4, 'h'; "H5.png", 5, 'h'; "H6.png", 6, 'h'; ...
            "H7.png", 7, 'h'; "H8.png", 8, 'h'; "H9.png", 9, 'h'; ...
            "H10.png", 10, 'h'; "HJ.png", 11, 'h'; "HQ.png", 12, 'h'; ...
            "HK.png", 13, 'h'; "HA.png", 14, 'h'; ...
            "S2.png", 2, 's'; "S3.png", 3, 's'; ...
            "S4.png", 4, 's'; "S5.png", 5, 's'; "S6.png", 6, 's'; ...
            "S7.png", 7, 's'; "S8.png", 8, 's'; "S9.png", 9, 's'; ...
            "S10.png", 10, 's'; "SJ.png", 11, 's'; "SQ.png", 12, 's'; ...
            "SK.png", 13, 's'; "SA.png", 14, 's'};

        %Shuffles cards
        shufCards = zeros(1, 52);

        %Counts number of cards dealt
        n = 0;

        %Represents which cards are in player's hand
        handCards = [0 0 0 0 0];

        %Locks cards which are held
        cardLock = [0 0 0 0 0];

        %Enables/disables the ability to hold cards
        holdAbility = 0;

        %Enable/Disable bet button
        betEnable = 1;

        %Reward tier
        %case 0 = no bet, case 1 = 10: 1x , case 2 = 20-40: 2x,
        %case 3 = 50-90: 3x, case 4 = 100-900: 5x, case 5 = 1000+: 10x

        betCase = 0;

        ps = 0; % Enables pause for certian cards
        rs = 0; % Denotes round reset
        h = [] % To change the properties of the panel
        hl = [] %UI control
        cl = 0; %collumn
        rw = 0; %row
        flsh = [0 0 0 0 0] %controls flash
    end

    methods
        function dealCards(app)
            %Create a matrix of Card Objects
            allCards = [app.Card1 app.Card2 app.Card3 app.Card4 app.Card5];
            app.betEnable = 0;
            %Deal cards
            for a = 1:5
                if app.ps ~= 0
                    pause(0.25)
                end

                %Animation for dealing cards
                for i = 0:151
                    allCards(a).Position = [allCards(a).Position(1) i 140 200];
                    pause(0.001)
                end

                app.ps = 1;
                app.n = app.n+1;
                allCards(a).ImageSource = app.cards{app.shufCards(app.n)};
                app.handCards(a) = app.shufCards(app.n);
            end
        end

        function checkWin(app) %Checks for winning hands

            C1val = app.cards{app.handCards(1), 2};
            C2val = app.cards{app.handCards(2), 2};
            C3val = app.cards{app.handCards(3), 2};
            C4val = app.cards{app.handCards(4), 2};
            C5val = app.cards{app.handCards(5), 2};
            values = [C1val, C2val, C3val, C4val, C5val];

            valueEdges = 1.5:1:14.5;
            valueCount = histcounts(values, valueEdges);
            sortVal = sort(values);
            sortVal4 = sortVal;
            sortVal4(5) = [];

            C1suit = app.cards{app.handCards(1), 3};
            C2suit = app.cards{app.handCards(2), 3};
            C3suit = app.cards{app.handCards(3), 3};
            C4suit = app.cards{app.handCards(4), 3};
            C5suit = app.cards{app.handCards(5), 3};
            suits = [C1suit, C2suit, C3suit, C4suit, C5suit];

            suitEdges = [98.5 99.5 100.5 104.5 115.5];
            suitCount = histcounts(double(suits), suitEdges);

            %Base reward amount
            reward = 10;

            %Reward multiplier (based on how much is in the pot)
            rewardMult = 1;

            %Reward cases/tiers
            %case 0 = no bet, case 1 = bet 10, case 2 = bet 20-40,
            %case 3 = bet 50-90, case 4 = bet 100-900, case 5 = bet 1000+

            switch app.betCase
                case 0
                    %does nothing, is the default case when initialized
                case 1
                    rewardMult = 1;
                case 2
                    rewardMult = 2;
                case 3
                    rewardMult = 3;
                case 4
                    rewardMult = 5;
                case 5
                    rewardMult = 10;
            end

            %Checks for winning hands and disperses rewards
            if any(suitCount == 5) && sortVal(1) == 10 %Check for Royal Flush
                app.YourMoney.Value = num2str((str2double(app.YourMoney.Value) + 25000*(str2double(app.Pot.Value))*rewardMult));
                app.rw = 1;

            elseif any(suitCount == 5) && (all(diff(sortVal) == 1) == 1 || (all(diff(sortVal4) == 1) == 1 && sortVal4(1) == 2 && sortVal(5) == 14)) %Check for Straight Flush
                app.YourMoney.Value = num2str((str2double(app.YourMoney.Value) + 2500*(str2double(app.Pot.Value))*rewardMult));
                app.rw = 2;

            elseif any(valueCount == 4) %Check for Four of a Kind
                app.YourMoney.Value = num2str((str2double(app.YourMoney.Value) + 150*(str2double(app.Pot.Value))*rewardMult));
                app.rw = 3;

            elseif any(valueCount == 3) && any(valueCount == 2) %Check for Full House
                app.YourMoney.Value = num2str((str2double(app.YourMoney.Value) + 25*(str2double(app.Pot.Value))*rewardMult));
                app.rw = 4;

            elseif any(suitCount == 5) %Check for Flush
                app.YourMoney.Value = num2str((str2double(app.YourMoney.Value) + 20*(str2double(app.Pot.Value))*rewardMult));
                app.rw = 5;

            elseif all(diff(sortVal) == 1) == 1 || (all(diff(sortVal4) == 1) == 1 && sortVal4(1) == 2 && sortVal(5) == 14) %Check for Straight
                app.YourMoney.Value = num2str((str2double(app.YourMoney.Value) + 10*(str2double(app.Pot.Value))*rewardMult));
                app.rw = 6;

            elseif any(valueCount == 3) %Check for Three of a Kind
                app.YourMoney.Value = num2str((str2double(app.YourMoney.Value) + 4*(str2double(app.Pot.Value))*rewardMult));
                app.rw = 7;

            elseif sum(valueCount == 2) == 2 %Check for Two Pair
                app.YourMoney.Value = num2str((str2double(app.YourMoney.Value) + 2*(str2double(app.Pot.Value))*rewardMult));
                app.rw = 8;

            elseif valueCount(10) == 2 || valueCount(11) == 2 || valueCount(12) == 2 || valueCount(13) == 2 %Check for Jacks or Better
                app.YourMoney.Value = num2str((str2double(app.YourMoney.Value) + (str2double(app.Pot.Value))*rewardMult));
                app.rw = 9;

            else
                app.WinningHand.Text = 'No winning hands';
            end
        end

        function DisplayResults(app)

            C1val = app.cards{app.handCards(1), 2};
            C2val = app.cards{app.handCards(2), 2};
            C3val = app.cards{app.handCards(3), 2};
            C4val = app.cards{app.handCards(4), 2};
            C5val = app.cards{app.handCards(5), 2};
            values = [C1val, C2val, C3val, C4val, C5val];

            valueEdges = 1.5:1:14.5;
            valueCount = histcounts(values, valueEdges);
            sortVal = sort(values);
            sortVal4 = sortVal;
            sortVal4(5) = [];

            C1suit = app.cards{app.handCards(1), 3};
            C2suit = app.cards{app.handCards(2), 3};
            C3suit = app.cards{app.handCards(3), 3};
            C4suit = app.cards{app.handCards(4), 3};
            C5suit = app.cards{app.handCards(5), 3};
            suits = [C1suit, C2suit, C3suit, C4suit, C5suit];

            suitEdges = [98.5 99.5 100.5 104.5 115.5];
            suitCount = histcounts(double(suits), suitEdges);

            %Checks for winning hands and disperses rewards
            if any(suitCount == 5) && sortVal(1) == 10 %Check for Royal Flush
                app.WinningHand.Text = 'Royal Flush!';
                while app.rs == 1
                    app.RoyalFlushLabel.FontColor = [0.10,0.13,0.31];
                    app.RoyalFlushLabel.BackgroundColor = [0.99,0.96,0.78];
                    set(app.hl(app.rw, app.cl),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(app.rw, app.cl),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(app.rw, app.cl),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(app.rw, app.cl),'BackgroundColor',[0.10,0.13,0.31])
                    app.RoyalFlushLabel.FontColor = [0.99,0.96,0.78];
                    app.RoyalFlushLabel.BackgroundColor = [0.10,0.13,0.31];
                    pause(0.25)
                end

            elseif any(suitCount == 5) && (all(diff(sortVal) == 1) == 1 || (all(diff(sortVal4) == 1) == 1 && sortVal4(1) == 2 && sortVal(5) == 14)) %Check for Straight Flush
                app.WinningHand.Text = 'Straight Flush!';
                while app.rs == 1
                    app.StraightFlushLabel.FontColor = [0.10,0.13,0.31];
                    app.StraightFlushLabel.BackgroundColor = [0.99,0.96,0.78];
                    set(app.hl(app.rw, app.cl),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(app.rw, app.cl),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(app.rw, app.cl),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(app.rw, app.cl),'BackgroundColor',[0.10,0.13,0.31])
                    app.StraightFlushLabel.FontColor = [0.99,0.96,0.78];
                    app.StraightFlushLabel.BackgroundColor = [0.10,0.13,0.31];
                    pause(0.25)
                end

            elseif any(valueCount == 4) %Check for Four of a Kind
                app.WinningHand.Text = 'Four of a Kind!';
                while app.rs == 1
                    app.ofaKind4Label.FontColor = [0.10,0.13,0.31];
                    app.ofaKind4Label.BackgroundColor = [0.99,0.96,0.78];
                    set(app.hl(app.rw, app.cl),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(app.rw, app.cl),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(app.rw, app.cl),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(app.rw, app.cl),'BackgroundColor',[0.10,0.13,0.31])
                    app.ofaKind4Label.FontColor = [0.99,0.96,0.78];
                    app.ofaKind4Label.BackgroundColor = [0.10,0.13,0.31];
                    pause(0.25)
                end

            elseif any(valueCount == 3) && any(valueCount == 2) %Check for Full House
                app.WinningHand.Text = 'Full House!';
                while app.rs == 1
                    app.FullHouseLabel.FontColor = [0.10,0.13,0.31];
                    app.FullHouseLabel.BackgroundColor = [0.99,0.96,0.78];
                    set(app.hl(app.rw, app.cl),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(app.rw, app.cl),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(app.rw, app.cl),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(app.rw, app.cl),'BackgroundColor',[0.10,0.13,0.31])
                    app.FullHouseLabel.FontColor = [0.99,0.96,0.78];
                    app.FullHouseLabel.BackgroundColor = [0.10,0.13,0.31];
                    pause(0.25)
                end

            elseif any(suitCount == 5) %Check for Flush
                app.WinningHand.Text = 'Flush!';
                while app.rs == 1
                    app.FlushLabel.FontColor = [0.10,0.13,0.31];
                    app.FlushLabel.BackgroundColor = [0.99,0.96,0.78];
                    set(app.hl(app.rw, app.cl),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(app.rw, app.cl),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(app.rw, app.cl),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(app.rw, app.cl),'BackgroundColor',[0.10,0.13,0.31])
                    app.FlushLabel.FontColor = [0.99,0.96,0.78];
                    app.FlushLabel.BackgroundColor = [0.10,0.13,0.31];
                    pause(0.25)
                end

            elseif all(diff(sortVal) == 1) == 1 || (all(diff(sortVal4) == 1) == 1 && sortVal4(1) == 2 && sortVal(5) == 14) %Check for Straight
                app.WinningHand.Text = 'Straight!';
                while app.rs == 1
                    app.StraightLabel.FontColor = [0.10,0.13,0.31];
                    app.StraightLabel.BackgroundColor = [0.99,0.96,0.78];
                    set(app.hl(app.rw, app.cl),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(app.rw, app.cl),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(app.rw, app.cl),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(app.rw, app.cl),'BackgroundColor',[0.10,0.13,0.31])
                    app.StraightLabel.FontColor = [0.99,0.96,0.78];
                    app.StraightLabel.BackgroundColor = [0.10,0.13,0.31];
                    pause(0.25)
                end

            elseif any(valueCount == 3) %Check for Three of a Kind
                app.WinningHand.Text = 'Three of a Kind!';
                while app.rs == 1
                    app.ofaKind3Label.FontColor = [0.10,0.13,0.31];
                    app.ofaKind3Label.BackgroundColor = [0.99,0.96,0.78];
                    set(app.hl(app.rw, app.cl),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(app.rw, app.cl),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(app.rw, app.cl),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(app.rw, app.cl),'BackgroundColor',[0.10,0.13,0.31])
                    app.ofaKind3Label.FontColor = [0.99,0.96,0.78];
                    app.ofaKind3Label.BackgroundColor = [0.10,0.13,0.31];
                    pause(0.25)
                end

            elseif sum(valueCount == 2) == 2 %Check for Two Pair
                app.WinningHand.Text = 'Two Pair!';
                while app.rs == 1
                    app.Pair2Label.FontColor = [0.10,0.13,0.31];
                    app.Pair2Label.BackgroundColor = [0.99,0.96,0.78];
                    set(app.hl(app.rw, app.cl),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(app.rw, app.cl),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(app.rw, app.cl),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(app.rw, app.cl),'BackgroundColor',[0.10,0.13,0.31])
                    app.Pair2Label.FontColor = [0.99,0.96,0.78];
                    app.Pair2Label.BackgroundColor = [0.10,0.13,0.31];
                    pause(0.25)
                end

            elseif valueCount(10) == 2 || valueCount(11) == 2 || valueCount(12) == 2 || valueCount(13) == 2 %Check for Jacks or Better
                app.WinningHand.Text = 'Jacks or Better!';
                while app.rs == 1
                    app.JacksorBetterLabel.FontColor = [0.10,0.13,0.31];
                    app.JacksorBetterLabel.BackgroundColor = [0.99,0.96,0.78];
                    set(app.hl(app.rw, app.cl),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(app.rw, app.cl),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(app.rw, app.cl),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(app.rw, app.cl),'BackgroundColor',[0.10,0.13,0.31])
                    app.JacksorBetterLabel.FontColor = [0.99,0.96,0.78];
                    app.JacksorBetterLabel.BackgroundColor = [0.10,0.13,0.31];
                    pause(0.25)
                end
            end
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Deal
        function DealButtonPushed(app, event)
            %Sound for shuffling deal
            [a,b] = audioread('shufflesound.wav');
            sound(a(1:269100,1)/2,b);

            %Deal Button Push Animation
            for i = 0:35
                app.Deal.Position = [496 i 230 71];
                pause(0.005)
            end


            app.Deal.Enable = 'off';
            app.Replace.Enable = 'off';
            app.BetButton.Enable = 'off';
            app.BetButtonSub.Enable = 'off';
            app.BetButton100.Enable = 'off';
            app.BetButtonSub100.Enable = 'off';

            %Code to end flashing
            app.rs = 0;
            app.flsh = [0 0 0 0 0];
            set(app.h,'FontColor',[0.99,0.96,0.78])
            set(app.h,'BackgroundColor',[0.10,0.13,0.31])

            app.cardLock = [0 0 0 0 0];
            app.C1Held.Visible = 'off';
            app.C2Held.Visible = 'off';
            app.C3Held.Visible = 'off';
            app.C4Held.Visible = 'off';
            app.C5Held.Visible = 'off';

            app.Card1.ImageSource = 'CardBack1.jpg';
            app.Card2.ImageSource = 'CardBack1.jpg';
            app.Card3.ImageSource = 'CardBack1.jpg';
            app.Card4.ImageSource = 'CardBack1.jpg';
            app.Card5.ImageSource = 'CardBack1.jpg';

            app.WinningHand.Text = '';

            app.shufCards = randperm(52);
            %Test each Winning Hand
            %app.shufCards = [9 10 11 12 13 randperm(47)]; %Royal Flush
            %app.shufCards = [15 16 17 18 19 randperm(47)]; %Straight Flush
            %app.shufCards = [14 15 16 17 26 randperm(47)]; %Straight Flush w Ace
            %app.shufCards = [3 16 29 42 1 randperm(47)]; %Four of a Kind
            %app.shufCards = [40 14 47 27 8 randperm(47)]; %Full House
            %app.shufCards = [46 5 19 30 21 randperm(47)]; %Straight
            %app.shufCards = [1 2 16 4 26 randperm(47)]; %Straight w Ace
            %app.shufCards = [27 29 34 30 39 randperm(47)]; %Flush
            %app.shufCards = [4 9 15 17 30 randperm(47)]; %Three of a Kind
            %app.shufCards = [5 17 31 30 21 randperm(47)]; %Two Pair
            %app.shufCards = [5 randperm(4) 17 31 30 21 randperm(43)]; %Two Pair
            %app.shufCards = [10 2 23 31 42 randperm(47)]; %Jacks or Better

            app.n = 0;

            %Deal Cards
            dealCards(app)

            app.holdAbility = 1;

            app.Replace.Enable = 'on';
        end

        % Button pushed function: Replace
        function ReplaceButtonPushed(app, event)
            %Replace cards sound
            [z,x] = audioread('replacesound.mp3');
            sound(z(90000:265000,1),x);

            %Game over sound
            [r,t] = audioread('gameover.mp3');

            %Replace Button Push Animation
            for i = 0:35
                app.Replace.Position = [739 i 230 71];
                pause(0.005)
            end

            app.Replace.Enable = 'off';
            app.BetButton.Enable = 'off';

            app.holdAbility = 0;
            app.ps = 0;
            app.rs = 1;
            allCards = [app.Card1 app.Card2 app.Card3 app.Card4 app.Card5];

            %Replaces cards
            for a = 1:5
                if app.cardLock(a) == 0
                    if app.ps == 1
                        pause(0.25)
                    end

                    %Animation for replacing cards
                    allCards(a).ImageSource = 'Cardback1.jpg';
                    for i = 0:151
                        allCards(a).Position = [allCards(a).Position(1) i 140 200];
                        pause(0.001)
                    end
                    
                    app.ps = 1;
                    app.n = app.n + 1;
                    allCards(a).ImageSource = app.cards{app.shufCards(app.n)};
                    app.handCards(a) = app.shufCards(app.n);
                end
            end
            app.ps = 1;


            %Check for winning hand
            checkWin(app)

            %Reset pot to zero
            app.Pot.Value = '0';
            if str2double(app.YourMoney.Value) == 0
                app.WinningHand.Text = 'Game Over!';
                sound(r(1:360000,1), t);
                % pause(2)
                % app.WinningHand.Text = 'Game Over!.';
                % pause(0.3)
                % app.WinningHand.Text = 'Game Over!..';
                % pause(0.3)
                % app.WinningHand.Text = 'Game Over!...';
                % pause(1)
                % app.WinningHand.Text = 'U';
                % pause(0.2)
                % app.WinningHand.Text = 'Un';
                % pause(0.2)
                % app.WinningHand.Text = 'Unl';
                % pause(0.2)
                % app.WinningHand.Text = 'Unle';
                % pause(0.2)
                % app.WinningHand.Text = 'Unles';
                % pause(0.2)
                % app.WinningHand.Text = 'Unless';
                % pause(0.2)
                % app.WinningHand.Text = 'Unless.';
                % pause(0.2)
                % app.WinningHand.Text = 'Unless..';
                % pause(0.2)
                % app.WinningHand.Text = 'Unless...';
                % pause(1)
                % app.YourMoney.Value = "1 Soul";
                % pause(1)
                % app.BetSoul.Enable = "on";
                % app.BetSoul.Visible = 'on';
                % app.BetButton.Visible = "off";
                % app.BetButtonSub.Visible = "off";
                % app.BetButton100.Visible = "off";
                % app.BetButtonSub100.Visible = "off";
            end


            %Check if out of money, enable/disable bet button and 'bet to
            %play again, note
            if str2double(app.YourMoney.Value) > 0
                app.playAgain.Visible = 'on';
                app.BetButton.Enable = 'on';
                app.BetButton100.Enable = 'on';
            elseif str2double(app.YourMoney.Value) <= 0
                app.BetButton.Enable = 'off';
            end

            %Displays results
            DisplayResults(app)
        end

        % Image clicked function: Card1
        function Card1HELD(app, event)
            %Cards flick sound
            [a,b] = audioread("Pokerflick.wav");

            if app.holdAbility == 1
                if app.cardLock(1) == 0
                    app.C1Held.Visible = 'on';
                    app.cardLock(1) = 1;
                    sound(a,b);
                elseif app.cardLock(1) == 1
                    app.C1Held.Visible = 'off';
                    app.cardLock(1) = 0;
                    sound(a,b);
                end
            end
        end

        % Image clicked function: Card2
        function Card2HELD(app, event)
            %Cards flick sound
            [a,b] = audioread("Pokerflick.wav");

            if app.holdAbility == 1
                if app.cardLock(2) == 0
                    app.C2Held.Visible = 'on';
                    app.cardLock(2) = 1;
                    sound(a,b);
                elseif app.cardLock(2) == 1
                    app.C2Held.Visible = 'off';
                    app.cardLock(2) = 0;
                    sound(a,b);
                end
            end
        end

        % Image clicked function: Card3
        function Card3HELD(app, event)
            %Cards flick sound
            [a,b] = audioread("Pokerflick.wav");

            if app.holdAbility == 1
                if app.cardLock(3) == 0
                    app.C3Held.Visible = 'on';
                    app.cardLock(3) = 1;
                    sound(a,b);
                elseif app.cardLock(3) == 1
                    app.C3Held.Visible = 'off';
                    app.cardLock(3) = 0;
                    sound(a,b);
                end
            end
        end

        % Image clicked function: Card4
        function Card4HELD(app, event)
            %Cards flick sound
            [a,b] = audioread("Pokerflick.wav");

            if app.holdAbility == 1
                if app.cardLock(4) == 0
                    app.C4Held.Visible = 'on';
                    app.cardLock(4) = 1;
                    sound(a,b);
                elseif app.cardLock(4) == 1
                    app.C4Held.Visible = 'off';
                    app.cardLock(4) = 0;
                    sound(a,b);
                end
            end
        end

        % Image clicked function: Card5
        function Card5HELD(app, event)
            %Cards flick sound
            [a,b] = audioread("Pokerflick.wav");

            if app.cardLock(5) == 0
                app.C5Held.Visible = 'on';
                app.cardLock(5) = 1;
                sound(a,b);
            elseif app.cardLock(5) == 1
                app.C5Held.Visible = 'off';
                app.cardLock(5) = 0;
                sound(a,b);
            end
        end

        % Button pushed function: BetButton
        function Bet(app, event)
            %Coin sound for betting
            [a,b] = audioread('Coinsound.wav');
            sound(a,b);

            %+10 Button Push Animation
            for i = 375:392
                app.BetButton.Position = [i 72 106 34];
                pause(0.0025)
            end
            for i = 375:375
                app.BetButton.Position = [i 72 106 34];
                pause(0.0025)
            end

            app.playAgain.Visible = 'off';
            app.Deal.Enable = 'on';
            app.h = findobj(app.Panel, '-property', 'FontColor');
            app.h(1:9) = [];
            app.h = app.h';
            app.hl = [app.h(1:5); app.h(6:10) ;app.h(11:15); app.h(16:20); ...
                app.h(21:25); app.h(26:30); app.h(31:35); app.h(36:40); ...
                app.h(41:45)];
            app.flsh = [0 0 0 0 0];


            %Transfer money from purse to pot
            app.Pot.Value = num2str(str2double(app.Pot.Value) + 10);
            app.YourMoney.Value = num2str(str2double(app.YourMoney.Value) - 10);

            %Reward cases/tiers
            %case 0 = no bet, case 1 = 10, case 2 = 20-40, case 3 = 50-90,
            %case 4 = 100-900, case 5 = 1000+

            if str2double(app.YourMoney.Value) <= 0
                app.BetButton.Enable = 'off';
                app.BetButton100.Enable = 'off';
            end

            if str2double(app.Pot.Value) > 0
                app.BetButtonSub.Enable = 'on';
                app.BetButtonSub100.Enable = 'on';
            end

            if str2double(app.Pot.Value) == 10
                app.betCase = 1;
                app.flsh = [1 0 0 0 0];
                set(app.h,'FontColor',[0.99,0.96,0.78])
                set(app.h,'BackgroundColor',[0.10,0.13,0.31])
                app.cl = 1;
                while app.flsh(1) == 1
                    set(app.hl(:,1),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(:,1),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(:,1),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(:,1),'BackgroundColor',[0.10,0.13,0.31])
                    pause(0.25)
                end
            elseif str2double(app.Pot.Value) >= 20 && str2double(app.Pot.Value) <= 40
                app.betCase = 2;
                app.flsh = [0 1 0 0 0];
                set(app.h,'FontColor',[0.99,0.96,0.78])
                set(app.h,'BackgroundColor',[0.10,0.13,0.31])
                app.cl = 2;
                while app.flsh(2) == 1
                    set(app.hl(:,2),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(:,2),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(:,2),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(:,2),'BackgroundColor',[0.10,0.13,0.31])
                    pause(0.25)
                end
            elseif str2double(app.Pot.Value) >= 50 && str2double(app.Pot.Value) <=90
                app.betCase = 3;
                app.flsh = [0 0 1 0 0];
                set(app.hl,'FontColor',[0.99,0.96,0.78])
                set(app.hl,'BackgroundColor',[0.10,0.13,0.31])
                app.cl = 3;
                while app.flsh(3) == 1
                    set(app.hl(:,3),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(:,3),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(:,3),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(:,3),'BackgroundColor',[0.10,0.13,0.31])
                    pause(0.25)
                end
            elseif str2double(app.Pot.Value) >= 100 && str2double(app.Pot.Value) <= 900
                app.betCase = 4;
                app.flsh = [0 0 0 1 0];
                set(app.h,'FontColor',[0.99,0.96,0.78])
                set(app.h,'BackgroundColor',[0.10,0.13,0.31])
                app.cl = 4;
                while app.flsh(4) == 1
                    set(app.hl(:,4),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(:,4),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(:,4),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(:,4),'BackgroundColor',[0.10,0.13,0.31])
                    pause(0.25)
                end
            elseif str2double(app.Pot.Value) >= 1000
                app.betCase = 5;
                app.flsh = [0 0 0 0 1];
                set(app.h,'FontColor',[0.99,0.96,0.78])
                set(app.h,'BackgroundColor',[0.10,0.13,0.31])
                app.cl = 5;
                while app.flsh(5) == 1
                    set(app.hl(:,5),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(:,5),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(:,5),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(:,5),'BackgroundColor',[0.10,0.13,0.31])
                    pause(0.25)
                end
            end

        end

        % Button pushed function: BetButton100
        function BetButton100Pushed(app, event)
            %Coin sound for betting
            [a,b] = audioread('Coinsound.wav');
            sound(a,b);

            %+100 Button Push Animation
            for i = 375:392
                app.BetButton100.Position = [i 35 106 34];
                pause(0.0025)
            end
            for i = 375:375
                app.BetButton100.Position = [i 35 106 34];
                pause(0.0025)
            end

            app.playAgain.Visible = 'off';
            app.Deal.Enable = 'on';
            app.h = findobj(app.Panel, '-property', 'FontColor');
            app.h(1:9) = [];
            app.h = app.h';
            app.hl = [app.h(1:5); app.h(6:10) ;app.h(11:15); app.h(16:20); ...
                app.h(21:25); app.h(26:30); app.h(31:35); app.h(36:40); ...
                app.h(41:45)];
            app.flsh = [0 0 0 0 0];


            %Transfer money from purse to pot
            if str2double(app.YourMoney.Value) >= 100
                app.Pot.Value = num2str(str2double(app.Pot.Value) + 100);
                app.YourMoney.Value = num2str(str2double(app.YourMoney.Value) - 100);
            else
                app.Pot.Value = num2str(str2double(app.Pot.Value) + str2double(app.YourMoney.Value));
                app.YourMoney.Value = num2str(0);
            end


            %Reward cases/tiers
            %case 0 = no bet, case 1 = 10, case 2 = 20-40, case 3 = 50-90,
            %case 4 = 100-900, case 5 = 1000+

            if str2double(app.Pot.Value) > 0
                app.BetButtonSub.Enable = 'on';
                app.BetButtonSub100.Enable = 'on';
            end

            if str2double(app.YourMoney.Value) <= 0
                app.BetButton.Enable = 'off';
                app.BetButton100.Enable = 'off';
            end

            if str2double(app.Pot.Value) == 10
                app.betCase = 1;
                app.flsh = [1 0 0 0 0];
                set(app.h,'FontColor',[0.99,0.96,0.78])
                set(app.h,'BackgroundColor',[0.10,0.13,0.31])
                app.cl = 1;
                while app.flsh(1) == 1
                    set(app.hl(:,1),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(:,1),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(:,1),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(:,1),'BackgroundColor',[0.10,0.13,0.31])
                    pause(0.25)
                end
            elseif str2double(app.Pot.Value) >= 20 && str2double(app.Pot.Value) <= 40
                app.betCase = 2;
                app.flsh = [0 1 0 0 0];
                set(app.h,'FontColor',[0.99,0.96,0.78])
                set(app.h,'BackgroundColor',[0.10,0.13,0.31])
                app.cl = 2;
                while app.flsh(2) == 1
                    set(app.hl(:,2),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(:,2),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(:,2),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(:,2),'BackgroundColor',[0.10,0.13,0.31])
                    pause(0.25)
                end
            elseif str2double(app.Pot.Value) >= 50 && str2double(app.Pot.Value) <=90
                app.betCase = 3;
                app.flsh = [0 0 1 0 0];
                set(app.hl,'FontColor',[0.99,0.96,0.78])
                set(app.hl,'BackgroundColor',[0.10,0.13,0.31])
                app.cl = 3;
                while app.flsh(3) == 1
                    set(app.hl(:,3),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(:,3),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(:,3),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(:,3),'BackgroundColor',[0.10,0.13,0.31])
                    pause(0.25)
                end
            elseif str2double(app.Pot.Value) >= 100 && str2double(app.Pot.Value) <= 900
                app.betCase = 4;
                app.flsh = [0 0 0 1 0];
                set(app.h,'FontColor',[0.99,0.96,0.78])
                set(app.h,'BackgroundColor',[0.10,0.13,0.31])
                app.cl = 4;
                while app.flsh(4) == 1
                    set(app.hl(:,4),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(:,4),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(:,4),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(:,4),'BackgroundColor',[0.10,0.13,0.31])
                    pause(0.25)
                end
            elseif str2double(app.Pot.Value) >= 1000
                app.betCase = 5;
                app.flsh = [0 0 0 0 1];
                set(app.h,'FontColor',[0.99,0.96,0.78])
                set(app.h,'BackgroundColor',[0.10,0.13,0.31])
                app.cl = 5;
                while app.flsh(5) == 1
                    set(app.hl(:,5),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(:,5),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(:,5),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(:,5),'BackgroundColor',[0.10,0.13,0.31])
                    pause(0.25)
                end
            end

        end

        % Button pushed function: BetButtonSub
        function BetButtonSubPushed(app, event)
            %Coin sound for betting
            [a,b] = audioread('Coinsound.wav');
            sound(a,b);

            %-10 Button Push Animation
            for i = 250:267
                app.BetButtonSub.Position = [i 72 106 34];
                pause(0.005)
            end

            app.playAgain.Visible = 'off';
            app.Deal.Enable = 'on';
            app.h = findobj(app.Panel, '-property', 'FontColor');
            app.h(1:9) = [];
            app.h = app.h';
            app.hl = [app.h(1:5); app.h(6:10) ;app.h(11:15); app.h(16:20); ...
                app.h(21:25); app.h(26:30); app.h(31:35); app.h(36:40); ...
                app.h(41:45)];
            app.flsh = [0 0 0 0 0];


            %Transfer money from purse to pot
            app.Pot.Value = num2str(str2double(app.Pot.Value) - 10);
            app.YourMoney.Value = num2str(str2double(app.YourMoney.Value) + 10);

            %Reward cases/tiers
            %case 0 = no bet, case 1 = 10, case 2 = 20-40, case 3 = 50-90,
            %case 4 = 100-900, case 5 = 1000+

            if str2double(app.YourMoney.Value) > 0
                app.BetButton.Enable = 'on';
                app.BetButton100.Enable = 'on';
            end

            if str2double(app.Pot.Value) <= 0
                app.BetButtonSub.Enable = 'off';
                app.BetButtonSub100.Enable = 'off';
                app.Deal.Enable = 'off';
            end

            if str2double(app.Pot.Value) == 10
                app.betCase = 1;
                app.flsh = [1 0 0 0 0];
                set(app.h,'FontColor',[0.99,0.96,0.78])
                set(app.h,'BackgroundColor',[0.10,0.13,0.31])
                app.cl = 1;
                while app.flsh(1) == 1
                    set(app.hl(:,1),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(:,1),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(:,1),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(:,1),'BackgroundColor',[0.10,0.13,0.31])
                    pause(0.25)
                end
            elseif str2double(app.Pot.Value) >= 20 && str2double(app.Pot.Value) <= 40
                app.betCase = 2;
                app.flsh = [0 1 0 0 0];
                set(app.h,'FontColor',[0.99,0.96,0.78])
                set(app.h,'BackgroundColor',[0.10,0.13,0.31])
                app.cl = 2;
                while app.flsh(2) == 1
                    set(app.hl(:,2),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(:,2),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(:,2),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(:,2),'BackgroundColor',[0.10,0.13,0.31])
                    pause(0.25)
                end
            elseif str2double(app.Pot.Value) >= 50 && str2double(app.Pot.Value) <=90
                app.betCase = 3;
                app.flsh = [0 0 1 0 0];
                set(app.hl,'FontColor',[0.99,0.96,0.78])
                set(app.hl,'BackgroundColor',[0.10,0.13,0.31])
                app.cl = 3;
                while app.flsh(3) == 1
                    set(app.hl(:,3),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(:,3),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(:,3),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(:,3),'BackgroundColor',[0.10,0.13,0.31])
                    pause(0.25)
                end
            elseif str2double(app.Pot.Value) >= 100 && str2double(app.Pot.Value) <= 900
                app.betCase = 4;
                app.flsh = [0 0 0 1 0];
                set(app.h,'FontColor',[0.99,0.96,0.78])
                set(app.h,'BackgroundColor',[0.10,0.13,0.31])
                app.cl = 4;
                while app.flsh(4) == 1
                    set(app.hl(:,4),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(:,4),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(:,4),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(:,4),'BackgroundColor',[0.10,0.13,0.31])
                    pause(0.25)
                end
            elseif str2double(app.Pot.Value) >= 1000
                app.betCase = 5;
                app.flsh = [0 0 0 0 1];
                set(app.h,'FontColor',[0.99,0.96,0.78])
                set(app.h,'BackgroundColor',[0.10,0.13,0.31])
                app.cl = 5;
                while app.flsh(5) == 1
                    set(app.hl(:,5),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(:,5),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(:,5),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(:,5),'BackgroundColor',[0.10,0.13,0.31])
                    pause(0.25)
                end
            end

        end

        % Button pushed function: BetButtonSub100
        function BetButtonSub100Pushed(app, event)
            %Coin sound for betting
            [a,b] = audioread('Coinsound.wav');
            sound(a,b);

            %-100 Button Push Animation
            for i = 250:267
                app.BetButtonSub100.Position = [i 35 106 34];
                pause(0.005)
            end

            app.playAgain.Visible = 'off';
            app.Deal.Enable = 'on';
            app.h = findobj(app.Panel, '-property', 'FontColor');
            app.h(1:9) = [];
            app.h = app.h';
            app.hl = [app.h(1:5); app.h(6:10) ;app.h(11:15); app.h(16:20); ...
                app.h(21:25); app.h(26:30); app.h(31:35); app.h(36:40); ...
                app.h(41:45)];
            app.flsh = [0 0 0 0 0];

            %Transfer money from purse to pot
            if str2double(app.Pot.Value) >= 100
                app.Pot.Value = num2str(str2double(app.Pot.Value) - 100);
                app.YourMoney.Value = num2str(str2double(app.YourMoney.Value) + 100);
            else
                app.YourMoney.Value = num2str(str2double(app.Pot.Value) + str2double(app.YourMoney.Value));
                app.Pot.Value = num2str(0);
            end

            %Reward cases/tiers
            %case 0 = no bet, case 1 = 10, case 2 = 20-40, case 3 = 50-90,
            %case 4 = 100-900, case 5 = 1000+

            if str2double(app.YourMoney.Value) > 0
                app.BetButton.Enable = 'on';
                app.BetButton100.Enable = 'on';
            end

            if str2double(app.Pot.Value) <= 0
                app.BetButtonSub.Enable = 'off';
                app.BetButtonSub100.Enable = 'off';
                app.Deal.Enable = 'off';
            end

            if str2double(app.Pot.Value) == 10
                app.betCase = 1;
                app.flsh = [1 0 0 0 0];
                set(app.h,'FontColor',[0.99,0.96,0.78])
                set(app.h,'BackgroundColor',[0.10,0.13,0.31])
                app.cl = 1;
                while app.flsh(1) == 1
                    set(app.hl(:,1),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(:,1),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(:,1),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(:,1),'BackgroundColor',[0.10,0.13,0.31])
                    pause(0.25)
                end
            elseif str2double(app.Pot.Value) >= 20 && str2double(app.Pot.Value) <= 40
                app.betCase = 2;
                app.flsh = [0 1 0 0 0];
                set(app.h,'FontColor',[0.99,0.96,0.78])
                set(app.h,'BackgroundColor',[0.10,0.13,0.31])
                app.cl = 2;
                while app.flsh(2) == 1
                    set(app.hl(:,2),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(:,2),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(:,2),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(:,2),'BackgroundColor',[0.10,0.13,0.31])
                    pause(0.25)
                end
            elseif str2double(app.Pot.Value) >= 50 && str2double(app.Pot.Value) <=90
                app.betCase = 3;
                app.flsh = [0 0 1 0 0];
                set(app.hl,'FontColor',[0.99,0.96,0.78])
                set(app.hl,'BackgroundColor',[0.10,0.13,0.31])
                app.cl = 3;
                while app.flsh(3) == 1
                    set(app.hl(:,3),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(:,3),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(:,3),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(:,3),'BackgroundColor',[0.10,0.13,0.31])
                    pause(0.25)
                end
            elseif str2double(app.Pot.Value) >= 100 && str2double(app.Pot.Value) <= 900
                app.betCase = 4;
                app.flsh = [0 0 0 1 0];
                set(app.h,'FontColor',[0.99,0.96,0.78])
                set(app.h,'BackgroundColor',[0.10,0.13,0.31])
                app.cl = 4;
                while app.flsh(4) == 1
                    set(app.hl(:,4),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(:,4),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(:,4),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(:,4),'BackgroundColor',[0.10,0.13,0.31])
                    pause(0.25)
                end
            elseif str2double(app.Pot.Value) >= 1000
                app.betCase = 5;
                app.flsh = [0 0 0 0 1];
                set(app.h,'FontColor',[0.99,0.96,0.78])
                set(app.h,'BackgroundColor',[0.10,0.13,0.31])
                app.cl = 5;
                while app.flsh(5) == 1
                    set(app.hl(:,5),'FontColor',[0.10,0.13,0.31])
                    set(app.hl(:,5),'BackgroundColor',[0.99,0.96,0.78])
                    pause(0.25)
                    set(app.hl(:,5),'FontColor',[0.99,0.96,0.78])
                    set(app.hl(:,5),'BackgroundColor',[0.10,0.13,0.31])
                    pause(0.25)
                end
            end

        end

        % Button pushed function: BetSoul
        function BetSoulButtonPushed(app, event)
            % %Coin sound for betting
            % [a,b] = audioread('Coinsound.wav');
            % sound(a,b);

            % app.YourMoney.Value = "0";
            % app.Pot.Value ="1 Soul";
            % set(app.h, 'BackgroundColor',[1.00,0.22,0.00])
            % set(app.h, 'FontColor',[0.63,0.14,0.00])
            % pause(0.1)
            % app.Image.ImageSource = "hellfire.jpg";
        end

        % Value changed function: BGM
        function BGMValueChanged(app, event)
            %BGM options
            [c,d] = audioread('Jazzbgm.mp3');
            [m,k] = audioread('Casinobgm.mp3');

            value = app.BGM.Value;
            disp(value)

            if strcmp(value,'No') == 1
                clear sound
            elseif strcmp(value, 'BGM 2') == 1
                clear sound
                sound(m/2,k);
            elseif strcmp(value, 'BGM 1') == 1
                clear sound
                sound(c/2,d);                
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            clear sound
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'diamond.png';
            app.BetSoul = uibutton(app.UIFigure, 'push');
            app.BetSoul.ButtonPushedFcn = createCallbackFcn(app, @BetSoulButtonPushed, true);
            app.BetSoul.Enable = 'off';
            app.BetSoul.Visible = 'off';
            app.BetSoul.Text = 'Bet Soul';
            app.BetButtonSub100 = uibutton(app.UIFigure, 'push');
            app.BetButtonSub100.ButtonPushedFcn = createCallbackFcn(app, @BetButtonSub100Pushed, true);
            app.BetButtonSub100.Enable = 'off';
            app.BetButtonSub100.Text = '--$100';
            app.BetButton100 = uibutton(app.UIFigure, 'push');
            app.BetButton100.ButtonPushedFcn = createCallbackFcn(app, @BetButton100Pushed, true);
            app.BetButton100.Text = '$100+';
            app.BetButtonSub = uibutton(app.UIFigure, 'push');
            app.BetButtonSub.ButtonPushedFcn = createCallbackFcn(app, @BetButtonSubPushed, true);
            app.BetButtonSub.Enable = 'off';
            app.BetButtonSub.Text = '--$10';
            app.BetButton = uibutton(app.UIFigure, 'push');
            app.BetButton.ButtonPushedFcn = createCallbackFcn(app, @Bet, true);
            app.BetButton.Text = '$10+';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Pot';
            app.YourMoneyLabel = uilabel(app.UIFigure);
            app.YourMoneyLabel.Text = 'Your Money';
            app.YourMoney = uieditfield(app.UIFigure, 'text');
            app.YourMoney.Value = '100';
            app.Pot = uieditfield(app.UIFigure, 'text');
            app.Pot.Value = '0';
            app.playAgain = uilabel(app.UIFigure);
            app.playAgain.Visible = 'off';
            app.playAgain.Text = 'Bet to play again!';
            app.Deal = uibutton(app.UIFigure, 'push');
            app.Deal.ButtonPushedFcn = createCallbackFcn(app, @DealButtonPushed, true);
            app.Deal.Enable = 'off';
            app.Deal.Text = 'Deal';
            app.Replace = uibutton(app.UIFigure, 'push');
            app.Replace.ButtonPushedFcn = createCallbackFcn(app, @ReplaceButtonPushed, true);
            app.Replace.Enable = 'off';
            app.Replace.Text = 'Replace';
            app.C1Held = uilabel(app.UIFigure);
            app.C1Held.Visible = 'off';
            app.C1Held.Text = 'HELD';
            app.C2Held = uilabel(app.UIFigure);
            app.C2Held.Visible = 'off';
            app.C2Held.Text = 'HELD';
            app.C3Held = uilabel(app.UIFigure);
            app.C3Held.Visible = 'off';
            app.C3Held.Text = 'HELD';
            app.C4Held = uilabel(app.UIFigure);
            app.C4Held.Visible = 'off';
            app.C4Held.Text = 'HELD';
            app.C5Held = uilabel(app.UIFigure);
            app.C5Held.Visible = 'off';
            app.C5Held.Text = 'HELD';
            app.Card5 = uiimage(app.UIFigure);
            app.Card5.ImageClickedFcn = createCallbackFcn(app, @Card5HELD, true);
            app.Card5.ImageSource = 'CardBack1.jpg';
            app.Card4 = uiimage(app.UIFigure);
            app.Card4.ImageClickedFcn = createCallbackFcn(app, @Card4HELD, true);
            app.Card4.ImageSource = 'CardBack1.jpg';
            app.Card3 = uiimage(app.UIFigure);
            app.Card3.ImageClickedFcn = createCallbackFcn(app, @Card3HELD, true);
            app.Card3.ImageSource = 'CardBack1.jpg';
            app.Card2 = uiimage(app.UIFigure);
            app.Card2.ImageClickedFcn = createCallbackFcn(app, @Card2HELD, true);
            app.Card2.ImageSource = 'CardBack1.jpg';
            app.Card1 = uiimage(app.UIFigure);
            app.Card1.ImageClickedFcn = createCallbackFcn(app, @Card1HELD, true);
            app.Card1.ImageSource = 'CardBack1.jpg';
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.B1 = uilabel(app.GridLayout);
            app.B1.Text = '';
            app.B2 = uilabel(app.GridLayout);
            app.B2.Text = '';
            app.B3 = uilabel(app.GridLayout);
            app.B3.Text = '';
            app.B4 = uilabel(app.GridLayout);
            app.B4.Text = '';
            app.B5 = uilabel(app.GridLayout);
            app.B5.Text = '';
            app.B6 = uilabel(app.GridLayout);
            app.B6.Text = '';
            app.B7 = uilabel(app.GridLayout);
            app.B7.Text = '';
            app.B8 = uilabel(app.GridLayout);
            app.B8.Text = '';
            app.B9 = uilabel(app.GridLayout);
            app.B9.Text = 'b';
            app.L11 = uilabel(app.GridLayout);
            app.L11.Text = '25000';
            app.L12 = uilabel(app.GridLayout);
            app.L12.Text = '50000';
            app.L13 = uilabel(app.GridLayout);
            app.L13.Text = '75000';
            app.L14 = uilabel(app.GridLayout);
            app.L14.Text = '125000';
            app.L15 = uilabel(app.GridLayout);
            app.L15.Text = '250000';
            app.L21 = uilabel(app.GridLayout);
            app.L21.Text = '2500';
            app.L22 = uilabel(app.GridLayout);
            app.L22.Text = '5000';
            app.L23 = uilabel(app.GridLayout);
            app.L23.Text = '7500';
            app.L24 = uilabel(app.GridLayout);
            app.L24.Text = '12500';
            app.L25 = uilabel(app.GridLayout);
            app.L25.Text = '25000';
            app.L31 = uilabel(app.GridLayout);
            app.L31.Text = '150';
            app.L32 = uilabel(app.GridLayout);
            app.L32.Text = '300';
            app.L33 = uilabel(app.GridLayout);
            app.L33.Text = '450';
            app.L34 = uilabel(app.GridLayout);
            app.L34.Text = '750';
            app.L35 = uilabel(app.GridLayout);
            app.L35.Text = '1500';
            app.L41 = uilabel(app.GridLayout);
            app.L41.Text = '25';
            app.L42 = uilabel(app.GridLayout);
            app.L42.Text = '50';
            app.L43 = uilabel(app.GridLayout);
            app.L43.Text = '75';
            app.L44 = uilabel(app.GridLayout);
            app.L44.Text = '125';
            app.L45 = uilabel(app.GridLayout);
            app.L45.Text = '250';
            app.L51 = uilabel(app.GridLayout);
            app.L51.Text = '20';
            app.L52 = uilabel(app.GridLayout);
            app.L52.Text = '40';
            app.L53 = uilabel(app.GridLayout);
            app.L53.Text = '60';
            app.L54 = uilabel(app.GridLayout);
            app.L54.Text = '100';
            app.L55 = uilabel(app.GridLayout);
            app.L55.Text = '200';
            app.L61 = uilabel(app.GridLayout);
            app.L61.Text = '10';
            app.L62 = uilabel(app.GridLayout);
            app.L62.Text = '20';
            app.L63 = uilabel(app.GridLayout);
            app.L63.Text = '30';
            app.L64 = uilabel(app.GridLayout);
            app.L64.Text = '50';
            app.L65 = uilabel(app.GridLayout);
            app.L65.Text = '100';
            app.L71 = uilabel(app.GridLayout);
            app.L71.Text = '4';
            app.L72 = uilabel(app.GridLayout);
            app.L72.Text = '8';
            app.L73 = uilabel(app.GridLayout);
            app.L73.Text = '12';
            app.L74 = uilabel(app.GridLayout);
            app.L74.Text = '20';
            app.L75 = uilabel(app.GridLayout);
            app.L75.Text = '40';
            app.L81 = uilabel(app.GridLayout);
            app.L81.Text = '2';
            app.L82 = uilabel(app.GridLayout);
            app.L82.Text = '4';
            app.L83 = uilabel(app.GridLayout);
            app.L83.Text = '6';
            app.L84 = uilabel(app.GridLayout);
            app.L84.Text = '10';
            app.L85 = uilabel(app.GridLayout);
            app.L85.Text = '20';
            app.L91 = uilabel(app.GridLayout);
            app.L91.Text = '1';
            app.L92 = uilabel(app.GridLayout);
            app.L92.Text = '2';
            app.L93 = uilabel(app.GridLayout);
            app.L93.Text = '3';
            app.L94 = uilabel(app.GridLayout);
            app.L94.Text = '5';
            app.L95 = uilabel(app.GridLayout);
            app.L95.Text = '10';
            app.JacksorBetterLabel = uilabel(app.GridLayout);
            app.JacksorBetterLabel.Text = 'Jacks or Better';
            app.Pair2Label = uilabel(app.GridLayout);
            app.Pair2Label.Text = '2 Pair';
            app.ofaKind3Label = uilabel(app.GridLayout);
            app.ofaKind3Label.Text = '3 of a Kind';
            app.StraightLabel = uilabel(app.GridLayout);
            app.StraightLabel.Text = 'Straight';
            app.FlushLabel = uilabel(app.GridLayout);
            app.FlushLabel.Text = 'Flush';
            app.FullHouseLabel = uilabel(app.GridLayout);
            app.FullHouseLabel.Text = 'Full House';
            app.ofaKind4Label = uilabel(app.GridLayout);
            app.ofaKind4Label.Text = '4 of a Kind';
            app.StraightFlushLabel = uilabel(app.GridLayout);
            app.StraightFlushLabel.Text = 'Straight Flush';
            app.RoyalFlushLabel = uilabel(app.GridLayout);
            app.RoyalFlushLabel.Text = 'Royal Flush';
            app.WinningHand = uilabel(app.UIFigure);
            app.WinningHand.Text = '';
            app.SoundLabel = uilabel(app.UIFigure);
            app.SoundLabel.Text = 'Music ♫';
            app.BGM = uidropdown(app.UIFigure);
            app.BGM.Items = {'No', 'BGM 1', 'BGM 2'};
            app.BGM.ValueChangedFcn = createCallbackFcn(app, @BGMValueChanged, true);
            app.BGM.Value = 'No';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LatestPokerUpdate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end